classdef DataInladen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea_2                     matlab.ui.control.TextArea
        Tree                           matlab.ui.container.CheckBoxTree
        EDFNode                        matlab.ui.container.TreeNode
        PCO2Node                       matlab.ui.container.TreeNode
        PCO2_DCNode                    matlab.ui.container.TreeNode
        SpO2Node                       matlab.ui.container.TreeNode
        PRNode                         matlab.ui.container.TreeNode
        GDTNode                        matlab.ui.container.TreeNode
        PCO2Node_2                     matlab.ui.container.TreeNode
        DataGDTinladenButton           matlab.ui.control.Button
        BestandsnaamGDTEditField       matlab.ui.control.EditField
        BestandsnaamGDTEditFieldLabel  matlab.ui.control.Label
        BestandsnaamEDFEditField       matlab.ui.control.EditField
        BestandsnaamEDFEditFieldLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        StartMatlabEDFfileAnalyzerButton  matlab.ui.control.Button
        DataEDFinladenButton           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataedf % to hold EDF data
        infoedf % to hold EDF fileinfo
        fs % Sample Rate
        colortable %Specifeert welke kleur bij welke datasoort hoort
        currentlyDrawnNodes
        
         
    end
    
    methods (Access = private)
     
        
        % function drawedfgraphs(app) %
        %     hold(app.UIAxes, 'on')
        %     t=(0:app.infoedf.NumDataRecords-1)/app.fs;
        % 
        %     %draw all checked nodes on first run or when an undrawn node
        %     %gets checked
        %     if (size(app.currentlyDrawnNodes) <= size(app.Tree.CheckedNodes))
        %         cla(app.UIAxes) %clear all previous plots
        %         for i=1:size(app.Tree.CheckedNodes) %For all checkednodes
        %             currentNode=app.Tree.CheckedNodes(i);
        %             if(isempty(currentNode.Children)) %Only draw when at bottom of tree
        %                 y=app.dataedf.(currentNode.Text);
        %                 plot(app.UIAxes,t,y,'DisplayName',currentNode.Text);             
        %             end           
        %         end
        % 
        %     %removeunchecked nodes
        %     else 
        %         toRemove=setdiff(app.currentlyDrawnNodes,app.Tree.CheckedNodes); % verschil tussen checkednodes en drawnnodes = Wat teveel geplot is
        %         for i=1:size(toRemove) %For all checkednodes
        %             currentNode=toRemove(i);
        %             if(isempty(currentNode.Children)) %Only remove when at bottom of tree
        %                 delete(findobj(app.UIAxes,'DisplayName',currentNode.Text));        
        %             end           
        %         end
        %     end
        %     legend(app.UIAxes);
        % 
        %     app.currentlyDrawnNodes=app.Tree.CheckedNodes; %Update huidige checkednodes
        % end
        
        function drawgdtandedfgraphs(app)
            hold(app.UIAxes, 'on')
            t=(0:app.infoedf.NumDataRecords-1)/app.fs;
            
            %draw all checked nodes on first run or when an undrawn node
            %gets checked
            if (size(app.currentlyDrawnNodes) <= size(app.Tree.CheckedNodes))
                cla(app.UIAxes) %clear all previous plots
                for i=1:size(app.Tree.CheckedNodes) %For all checkednodes
                    currentNode=app.Tree.CheckedNodes(i);
                    if(isempty(currentNode.Children)) %Only draw when at bottom of tree
                        if(currentNode.Parent.Text == 'EDF')
                            y=app.dataedf.(currentNode.Text);
                            plot(app.UIAxes,t,y,'DisplayName',(append('EDF ',currentNode.Text)));
                        elseif(currentNode.Parent.Text == 'GDT')
                           %plot gdt hier
                        end
                    end           
                end

            %removeunchecked nodes
            else 
                toRemove=setdiff(app.currentlyDrawnNodes,app.Tree.CheckedNodes); % verschil tussen checkednodes en drawnnodes = Wat teveel geplot is
                for i=1:size(toRemove) %For all checkednodes
                    currentNode=toRemove(i);
                    if(isempty(currentNode.Children)) %Only remove when at bottom of tree
                        if(currentNode.Parent.Text == 'EDF')
                            delete(findobj(app.UIAxes,'DisplayName',(append('EDF ',currentNode.Text))));
                        elseif(currentNode.Parent.Text == 'GDT')
                           %delete(findobj(app.UIAxes,'DisplayName',(append('GDT ',currentNode.Text)));
                        end
                                
                    end           
                end
            end
            legend(app.UIAxes);

            app.currentlyDrawnNodes=app.Tree.CheckedNodes; %Update huidige checkednodes
            
            end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

        end

        % Callback function
        function DataEDFInladenButtonValueChanged(app, event)

        end

        % Button pushed function: DataEDFinladenButton
        function DataEDFinladenButtonPushed(app, event)
            cla(app.UIAxes) % clear previous plots
            delete(app.currentlyDrawnNodes) %
            fileName = app.BestandsnaamEDFEditField.Value
            app.dataedf = edfread(fileName)
            app.infoedf = edfinfo(fileName)

            %Calculate sample rate:
            numSamples = app.infoedf.NumDataRecords;
            testDuration = app.infoedf.NumDataRecords;
            app.fs=numSamples/testDuration;

            %Show edf parameters
            textforconsole = sprintf("Patient: " + app.infoedf.Patient + "\nDate: " + app.infoedf.StartDate + "\nTime: " + app.infoedf.StartTime + "\nNumDataRecords: " + app.infoedf.NumDataRecords + "\nSample Rate: " + app.fs + " Hz")
            app.TextArea.Value = textforconsole
            
            %Assign colors to Signallabels
            % app.colortable=table(app.infoedf.SignalLabels,color)
            % for i=1:size(app.infoedf.SignalLabels)
            % 
            % end


            drawgdtandedfgraphs(app) %draw graphs function

        end

        % Callback function
        function EDFinfoButtonPushed(app, event)
            edfFileAnalyzer(app.dataedf)
        end

        % Button pushed function: StartMatlabEDFfileAnalyzerButton
        function TextAreaValueChanging(app, event)

        end

        % Value changed function: BestandsnaamEDFEditField
        function BestandsnaamEDFEditFieldValueChanged(app, event)

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % checkedNodes = app.Tree.CheckedNodes;
            drawgdtandedfgraphs(app)
        end

        % Button pushed function: DataGDTinladenButton
        function DataGDTinladenButtonPushed(app, event)
        %%Placeholder
        lines = readlines("ANON_19810211.GDT");
        testing= false;
        for c = 1:lines.size
            currentLine = lines(c);
            if currentLine.contains("Time") && currentLine.contains("Load")
                testing = true;
                testStart= c
            end
            if currentLine.contains("***") && testing== true
                testEnd = c
                testing = false;
            end
        end
        for j=1:(testEnd-testStart)
            testLines(j,1)=lines(j+testStart-1);
        end
        testTable=zeros(13);
        for i=1:testLines.size(1)
            splitLine=transpose(split(testLines(i)));
            lineLenght = splitLine.size;
            splitLine=cat(2,splitLine,zeros(1,(13-lineLenght(2))));
            testTable(i,:)= splitLine;
        end
        testTable(1,:)=[];
        testTable(1,:)=[];
        testTable(:,2)=[];
        isNan=isnan(testTable(:,2))
        for j=size(testTable,1):-1:1
            if sum(isNan(j))>=1
                j
                testTable(j,:)=[];
            end
        end
        testTable(:,1)=transpose(0:10:10*size(testTable,1)-1);
        testTable
        plot(testTable(:,1),testTable(:,2))
        hold on
        plot(testTable(:,1),testTable(:,3))
        plot(testTable(:,1),testTable(:,4))
        plot(testTable(:,1),testTable(:,5))
        plot(testTable(:,1),testTable(:,6))
        plot(testTable(:,1),testTable(:,7))
        plot(testTable(:,1),testTable(:,8))
        plot(testTable(:,1),testTable(:,9))
        plot(testTable(:,1),testTable(:,10))
        plot(testTable(:,1),testTable(:,11))
        plot(testTable(:,1),testTable(:,12))
        legend('load','HR','VCO2/kg','VCO2/kg','VE','BF','EqO2','EqCO2','RER','O2pulse','PaCO2')
        dataGDT=array2timetable(testTable,'SampleRate',0.1)
        %%Placeholder
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataEDFinladenButton = uibutton(app.UIFigure, 'push');
            app.DataEDFinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataEDFinladenButtonPushed, true);
            app.DataEDFinladenButton.Text = 'Data EDF inladen';
            app.StartMatlabEDFfileAnalyzerButton = uibutton(app.UIFigure, 'push');
            app.StartMatlabEDFfileAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.StartMatlabEDFfileAnalyzerButton.Text = 'Start Matlab EDFfileAnalyzer';
            app.TextArea = uitextarea(app.UIFigure);
            app.BestandsnaamEDFEditFieldLabel = uilabel(app.UIFigure);
            app.BestandsnaamEDFEditFieldLabel.Text = 'Bestandsnaam EDF';
            app.BestandsnaamEDFEditField = uieditfield(app.UIFigure, 'text');
            app.BestandsnaamEDFEditField.ValueChangedFcn = createCallbackFcn(app, @BestandsnaamEDFEditFieldValueChanged, true);
            app.BestandsnaamEDFEditField.Value = 'GD capno.edf';
            app.BestandsnaamGDTEditFieldLabel = uilabel(app.UIFigure);
            app.BestandsnaamGDTEditFieldLabel.Text = 'Bestandsnaam GDT';
            app.BestandsnaamGDTEditField = uieditfield(app.UIFigure, 'text');
            app.DataGDTinladenButton = uibutton(app.UIFigure, 'push');
            app.DataGDTinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataGDTinladenButtonPushed, true);
            app.DataGDTinladenButton.Text = 'Data GDT inladen';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.EDFNode = uitreenode(app.Tree);
            app.EDFNode.Text = 'EDF';
            app.PCO2Node = uitreenode(app.EDFNode);
            app.PCO2Node.Text = 'PCO2';
            app.PCO2_DCNode = uitreenode(app.EDFNode);
            app.PCO2_DCNode.Text = 'PCO2_DC';
            app.SpO2Node = uitreenode(app.EDFNode);
            app.SpO2Node.Text = 'SpO2';
            app.PRNode = uitreenode(app.EDFNode);
            app.PRNode.Text = 'PR';
            app.GDTNode = uitreenode(app.Tree);
            app.GDTNode.Text = 'GDT';
            app.PCO2Node_2 = uitreenode(app.GDTNode);
            app.PCO2Node_2.Text = 'PCO2';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.PCO2Node, app.PCO2_DCNode, app.SpO2Node, app.PRNode, app.GDTNode, app.EDFNode, app.PCO2Node_2];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.TextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataInladen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end